classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ECE575ProjectUIFigure          matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout2                    matlab.ui.container.GridLayout
        ECE575CellSynchronizationProjectLabel  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        TabGroup                       matlab.ui.container.TabGroup
        LTETab                         matlab.ui.container.Tab
        GridLayout3                    matlab.ui.container.GridLayout
        PathGain2EditField             matlab.ui.control.NumericEditField
        PathGain2EditFieldLabel        matlab.ui.control.Label
        PathGain1EditField             matlab.ui.control.NumericEditField
        PathGain1EditFieldLabel        matlab.ui.control.Label
        NumofAntDropDown               matlab.ui.control.DropDown
        NumofAntDropDownLabel          matlab.ui.control.Label
        DuplexModeDropDown             matlab.ui.control.DropDown
        DuplexModeDropDownLabel        matlab.ui.control.Label
        CodingRateDropDown             matlab.ui.control.DropDown
        CodingRateDropDownLabel        matlab.ui.control.Label
        ModulationDropDown             matlab.ui.control.DropDown
        ModulationDropDownLabel        matlab.ui.control.Label
        BandwidthDropDown              matlab.ui.control.DropDown
        BandwidthDropDownLabel         matlab.ui.control.Label
        ResultsDirectoryLabel          matlab.ui.control.Label
        resultDir                      matlab.ui.control.Label
        resNumOfLay                    matlab.ui.control.Label
        NumofLayersLabel               matlab.ui.control.Label
        resTxScheme                    matlab.ui.control.Label
        TxSchemeLabel                  matlab.ui.control.Label
        resCSI                         matlab.ui.control.Label
        CSILabel                       matlab.ui.control.Label
        resModul                       matlab.ui.control.Label
        ModulationLabel_2              matlab.ui.control.Label
        resNg                          matlab.ui.control.Label
        resNumSubf                     matlab.ui.control.Label
        duplexResMode                  matlab.ui.control.Label
        NgLabel                        matlab.ui.control.Label
        NumSubfrLabel                  matlab.ui.control.Label
        resCellId                      matlab.ui.control.Label
        DuplexModeLabel_2              matlab.ui.control.Label
        CellIDLabel_5                  matlab.ui.control.Label
        ndlrb                          matlab.ui.control.Label
        NDLRBLabel                     matlab.ui.control.Label
        Delay2EditField                matlab.ui.control.NumericEditField
        Delay2EditFieldLabel           matlab.ui.control.Label
        rnti                           matlab.ui.control.Label
        RNTILabel                      matlab.ui.control.Label
        sibcrc                         matlab.ui.control.Label
        SIB1CRCLabel                   matlab.ui.control.Label
        pdschPeakEvm                   matlab.ui.control.Label
        PDSCHPeakEVMLabel              matlab.ui.control.Label
        rmsEVM                         matlab.ui.control.Label
        PDSCHRMSEVMLabel               matlab.ui.control.Label
        DecodedLamp                    matlab.ui.control.Lamp
        DecodedLampLabel               matlab.ui.control.Label
        DecodingLamp                   matlab.ui.control.Lamp
        DecodingLampLabel              matlab.ui.control.Label
        ReceivingLamp                  matlab.ui.control.Lamp
        ReceivingLampLabel             matlab.ui.control.Label
        TransmittingLamp               matlab.ui.control.Lamp
        TransmittingLampLabel          matlab.ui.control.Label
        ResultsLabel                   matlab.ui.control.Label
        Delay1EditField                matlab.ui.control.NumericEditField
        Delay1EditFieldLabel           matlab.ui.control.Label
        SimulationLabel                matlab.ui.control.Label
        RunningLamp                    matlab.ui.control.Lamp
        RunningLampLabel               matlab.ui.control.Label
        GapSpinner                     matlab.ui.control.Spinner
        GapSpinnerLabel                matlab.ui.control.Label
        VRBLengthSpinner               matlab.ui.control.Spinner
        VRBLengthSpinnerLabel          matlab.ui.control.Label
        VRBSTARTSpinner                matlab.ui.control.Spinner
        VRBSTARTSpinnerLabel           matlab.ui.control.Label
        DCIFormatDropDown              matlab.ui.control.DropDown
        DCIFormatDropDownLabel         matlab.ui.control.Label
        SNREditField                   matlab.ui.control.NumericEditField
        SNREditFieldLabel              matlab.ui.control.Label
        cellRefPorts                   matlab.ui.control.Label
        CellRefPortsLabel              matlab.ui.control.Label
        numOfFrame                     matlab.ui.control.Label
        NumberofFrameLabel             matlab.ui.control.Label
        totSubframes                   matlab.ui.control.Label
        TotalSubframesLabel            matlab.ui.control.Label
        cfi                            matlab.ui.control.Label
        CFILabel                       matlab.ui.control.Label
        RUNButton_2                    matlab.ui.control.Button
        RESETButton                    matlab.ui.control.Button
        phichDuration                  matlab.ui.control.Label
        PHICHDurationLabel             matlab.ui.control.Label
        ParametersLabel                matlab.ui.control.Label
        CycPrefixDropDown              matlab.ui.control.DropDown
        CycPrefixLabel                 matlab.ui.control.Label
        SubframesEditField             matlab.ui.control.NumericEditField
        SubframesEditFieldLabel        matlab.ui.control.Label
        SIBLabel                       matlab.ui.control.Label
        SIBEnableCheckBox              matlab.ui.control.CheckBox
        RNTIEditField                  matlab.ui.control.NumericEditField
        RNTIEditFieldLabel             matlab.ui.control.Label
        CELLIDEditField                matlab.ui.control.NumericEditField
        CELLIDEditFieldLabel           matlab.ui.control.Label
        LTERefModeDropDown             matlab.ui.control.DropDown
        LTERefModeDropDownLabel        matlab.ui.control.Label
        ChannelModelButtonGroup        matlab.ui.container.ButtonGroup
        RayleighButton                 matlab.ui.control.RadioButton
        AWGNButton_2                   matlab.ui.control.RadioButton
        GTab                           matlab.ui.container.Tab
        GridLayout3_2                  matlab.ui.container.GridLayout
        dcicrc                         matlab.ui.control.Label
        DCICRCLabel                    matlab.ui.control.Label
        nrbNr                          matlab.ui.control.Label
        NumberofresourceblocksLabel    matlab.ui.control.Label
        codingRate                     matlab.ui.control.Label
        CodingRateLabel                matlab.ui.control.Label
        IndexofSSBboostEditField       matlab.ui.control.NumericEditField
        IndexofSSBboostEditFieldLabel  matlab.ui.control.Label
        SNRBoostBeamformingEditField   matlab.ui.control.NumericEditField
        SNRBoostBeamformingEditFieldLabel  matlab.ui.control.Label
        PathGain2EditField_2           matlab.ui.control.NumericEditField
        PathGain2EditField_2Label      matlab.ui.control.Label
        PathGain1EditField_2           matlab.ui.control.NumericEditField
        PathGain1EditField_2Label      matlab.ui.control.Label
        NumofAntDropDown_2             matlab.ui.control.DropDown
        NumofAntDropDown_2Label        matlab.ui.control.Label
        ModulationDropDown_2           matlab.ui.control.DropDown
        ModulationDropDown_2Label      matlab.ui.control.Label
        BandwidthDropDown_2            matlab.ui.control.DropDown
        BandwidthDropDown_2Label       matlab.ui.control.Label
        ResultsDirectoryLabel_2        matlab.ui.control.Label
        resultDir_2                    matlab.ui.control.Label
        subCarrierSpace                matlab.ui.control.Label
        SubcarrierSpLabel              matlab.ui.control.Label
        cyclePrefix                    matlab.ui.control.Label
        CyclePrefixLabel               matlab.ui.control.Label
        resCSI_2                       matlab.ui.control.Label
        CSILabel_2                     matlab.ui.control.Label
        resModul_2                     matlab.ui.control.Label
        ModulationLabel_3              matlab.ui.control.Label
        DMRSTypeAPosition              matlab.ui.control.Label
        resNumSubf_2                   matlab.ui.control.Label
        DMRSTypeAPositionLabel         matlab.ui.control.Label
        NumSubfrLabel_2                matlab.ui.control.Label
        resCellId_2                    matlab.ui.control.Label
        CellIDLabel_6                  matlab.ui.control.Label
        ndlrb_2                        matlab.ui.control.Label
        NDLRBLabel_2                   matlab.ui.control.Label
        Delay2EditField_2              matlab.ui.control.NumericEditField
        Delay2EditField_2Label         matlab.ui.control.Label
        sibcrc_2                       matlab.ui.control.Label
        SIB1CRCLabel_2                 matlab.ui.control.Label
        pdcchRmsEvm                    matlab.ui.control.Label
        PDCCHRMSEVMLabel               matlab.ui.control.Label
        pdschrmsevm                    matlab.ui.control.Label
        PDSCHRMSEVMLabel_2             matlab.ui.control.Label
        DecodedLamp_2                  matlab.ui.control.Lamp
        DecodedLamp_2Label             matlab.ui.control.Label
        DecodingLamp_2                 matlab.ui.control.Lamp
        DecodingLamp_2Label            matlab.ui.control.Label
        ReceivingLamp_2                matlab.ui.control.Lamp
        ReceivingLamp_2Label           matlab.ui.control.Label
        TransmittingLamp_2             matlab.ui.control.Lamp
        TransmittingLamp_2Label        matlab.ui.control.Label
        ResultsLabel_2                 matlab.ui.control.Label
        Delay1EditField_2              matlab.ui.control.NumericEditField
        Delay1EditField_2Label         matlab.ui.control.Label
        SimulationLabel_2              matlab.ui.control.Label
        RunningLamp_2                  matlab.ui.control.Lamp
        RunningLamp_2Label             matlab.ui.control.Label
        SNREditField_2                 matlab.ui.control.NumericEditField
        SNREditField_2Label            matlab.ui.control.Label
        RUNButton_3                    matlab.ui.control.Button
        RESETButton_2                  matlab.ui.control.Button
        ParametersLabel_2              matlab.ui.control.Label
        MinChannelBWMHzDropDown        matlab.ui.control.DropDown
        MinChannelBWMHzDropDownLabel   matlab.ui.control.Label
        nrbSSBEditField                matlab.ui.control.NumericEditField
        nrbSSBEditFieldLabel           matlab.ui.control.Label
        SIBLabel_2                     matlab.ui.control.Label
        SIBEnableCheckBox_2            matlab.ui.control.CheckBox
        SubcarrierSpacingCommonEditField  matlab.ui.control.NumericEditField
        SubcarrierSpacingCommonEditFieldLabel  matlab.ui.control.Label
        CELLIDEditField_2              matlab.ui.control.NumericEditField
        CELLIDEditField_2Label         matlab.ui.control.Label
        BlockPatternDropDown           matlab.ui.control.DropDown
        BlockPatternDropDownLabel      matlab.ui.control.Label
        ChannelModelButtonGroup_2      matlab.ui.container.ButtonGroup
        RayleighButton_2               matlab.ui.control.RadioButton
        AWGNButton_3                   matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        rmc
        result
        fname
        fnRoot
        nrConfig
        wavegenConfig
        bw
        resBlk
        lteSet
        nrSet
    end
    
    methods (Access = private)
        
        function initLte(app, start)
            
            if (~start)
                app.rmc.PDSCH.Modulation{1} = app.ModulationDropDown.Value;
                app.rmc.NCellID = app.CELLIDEditField.Value;
                app.rmc.TotSubframes = app.SubframesEditField.Value; % Resource 
                app.rmc.PDSCH.RNTI = app.RNTIEditField.Value;

                app.rmc.DuplexMode = app.DuplexModeDropDown.Value;
                app.rmc.PDSCH.NLayers = str2num(app.NumofAntDropDown.Value);
                app.rmc.PHICHDuration = str2num(app.phichDuration.Text);
                app.rmc.CFI = str2num(app.cfi.Text);
                app.rmc.TotSubframes = str2num(app.totSubframes.Text);
                app.rmc.NFrame = str2num(app.numOfFrame.Text);
                app.rmc.CellRefP = str2num(app.cellRefPorts.Text);
                app.rmc.CyclicPrefix = app.CycPrefixDropDown.Value;

                % SIB parameters
                if (app.SIBEnableCheckBox.Value)
                    app.rmc.SIB.Enable = 'On';
                else
                    app.rmc.SIB.Enable = 'Off';
                end
                
                app.rmc.SIB.DCIFormat = app.DCIFormatDropDown.Value;
                app.rmc.SIB.AllocationType = 0;
                app.rmc.SIB.VRBStart = app.VRBSTARTSpinner.Value;
                app.rmc.SIB.VRBLength = app.VRBLengthSpinner.Value;
                app.rmc.SIB.Gap = app.GapSpinner.Value;
                % SIB data field filled with random bits, this is not a valid SIB
                % message
                app.rmc.SIB.Data = randi([0 1],176,1);
            else 
                setBandwidth(app);
                setCodeRate(app);
                app.ModulationDropDown.Value = app.rmc.PDSCH.Modulation{1};
                app.DuplexModeDropDown.Value = app.rmc.DuplexMode;
                app.NumofAntDropDown.Value = num2str(app.rmc.PDSCH.NLayers);
                app.phichDuration.Text = num2str(app.rmc.PHICHDuration);
                app.cfi.Text = num2str(app.rmc.CFI);
                app.totSubframes.Text = num2str(app.rmc.TotSubframes);
                app.numOfFrame.Text = num2str(app.rmc.NFrame);
                app.cellRefPorts.Text = num2str(app.rmc.CellRefP);
                app.CycPrefixDropDown.Value = app.rmc.CyclicPrefix;
                
            end
            
        end
        
        function initNR(app, start)
            
            if (~start)
                app.nrConfig.NCellID = app.CELLIDEditField_2.Value;
                app.nrConfig.BlockPattern = app.BlockPatternDropDown.Value;         % FR1: 'Case A','Case B','Case C'. FR2: 'Case D','Case E'

                app.nrConfig.TransmittedBlocks = ones(1,8);   % Bitmap of SS blocks transmitted
                app.nrConfig.SubcarrierSpacingCommon = app.SubcarrierSpacingCommonEditField.Value;    % SIB1 subcarrier spacing in kHz (15 or 30 for FR1. 60 or 120 for FR2)
                app.nrConfig.EnableSIB1 = app.SIBEnableCheckBox_2.Value;                  % Set to 0 to disable SIB1
                app.nrConfig.Modulation = app.ModulationDropDown_2.Value;

                % Configure an SS burst
                % Set the minimum channel bandwidth for the NR band required to
                % configure CORESET0 in FR1 (See TS 38.101-1 Table 5.3.5-1)
                app.nrConfig.MinChannelBW = app.MinChannelBWMHzDropDown.Value; % 5, 10, 40 MHz

                ssbSpace = hSSBurstSubcarrierSpacing(app.nrConfig.BlockPattern);
            
                bandw = extract(app.BandwidthDropDown_2.Value,digitsPattern);
                app.bw = str2num(bandw{1});
                                            
                % Set the minimum channel bandwidth for the NR band required to
                % configure CORESET0 in FR1 (See TS 38.101-1 Table 5.3.5-1)
                switch ssbSpace
                    case 15
                        app.nrConfig.Bandwidth = 50;
                        app.resBlk = ceilDiv(app.bw, 0.36);
                        app.nrConfig.NSizeGrid = app.resBlk;
                    case 30
                        app.nrConfig.Bandwidth = 100;
                        app.resBlk = ceilDiv(app.bw, 0.36);
                        app.nrConfig.NSizeGrid = app.resBlk; 
                    case 60
                        app.nrConfig.Bandwidth = 200;
                        app.resBlk = ceilDiv(app.bw, 0.72);
                        app.nrConfig.NSizeGrid = app.resBlk; 
                    case 120
                        app.nrConfig.Bandwidth = 400;
                        app.resBlk = ceilDiv(app.bw, 1.44);
                        app.nrConfig.NSizeGrid = app.resBlk; 
                    case 240
                        app.nrConfig.Bandwidth = 400;
                        app.resBlk = ceilDiv(app.bw, 2.88);
                        app.nrConfig.NSizeGrid = app.resBlk; 
                end
                app.nrbNr.Text = num2str(app.resBlk);
                app.wavegenConfig = hSIB1WaveformConfiguration(app.nrConfig);
                
            else
                app.nrConfig = struct();
                
                app.nrConfig.NCellID = 102;
                
                % Configure an SS burst
                app.nrConfig.BlockPattern = 'Case B';         % FR1: 'Case A','Case B','Case C'. FR2: 'Case D','Case E'
                app.nrConfig.TransmittedBlocks = ones(1,8);   % Bitmap of SS blocks transmitted
                app.nrConfig.SubcarrierSpacingCommon = 15;    % SIB1 subcarrier spacing in kHz (15 or 30 for FR1. 60 or 120 for FR2)
                app.nrConfig.EnableSIB1 = 1;                  % Set to 0 to disable SIB1

                app.nrConfig.Modulation = 'QPSK';
                app.ModulationDropDown_2.Value = 'QPSK';

                app.nrConfig.Bandwidth = 50;
                % Set the minimum channel bandwidth for the NR band required to
                % configure CORESET0 in FR1 (See TS 38.101-1 Table 5.3.5-1)
                app.nrConfig.MinChannelBW = 5; % 5, 10, 40 MHz
                app.wavegenConfig = hSIB1WaveformConfiguration(app.nrConfig);

                app.codingRate.Text = num2str(app.wavegenConfig.PDSCH{1}.TargetCodeRate);

                ssbSpace = hSSBurstSubcarrierSpacing(app.nrConfig.BlockPattern);
            
                bandw = extract(app.BandwidthDropDown_2.Value,digitsPattern);
                app.bw = str2num(bandw{1});
                                            
                % Set the minimum channel bandwidth for the NR band required to
                % configure CORESET0 in FR1 (See TS 38.101-1 Table 5.3.5-1)
                switch ssbSpace
                    case 15
                        app.nrConfig.Bandwidth = 50;
                        app.resBlk = ceilDiv(app.bw, 0.36);
                        app.nrConfig.NSizeGrid = app.resBlk;
                    case 30
                        app.nrConfig.Bandwidth = 100;
                        app.resBlk = ceilDiv(app.bw, 0.36);
                        app.nrConfig.NSizeGrid = app.resBlk; 
                    case 60
                        app.nrConfig.Bandwidth = 200;
                        app.resBlk = ceilDiv(app.bw, 0.72);
                        app.nrConfig.NSizeGrid = app.resBlk; 
                    case 120
                        app.nrConfig.Bandwidth = 400;
                        app.resBlk = ceilDiv(app.bw, 1.44);
                        app.nrConfig.NSizeGrid = app.resBlk; 
                    case 240
                        app.nrConfig.Bandwidth = 400;
                        app.resBlk = ceilDiv(app.bw, 2.88);
                        app.nrConfig.NSizeGrid = app.resBlk; 
                end
                
                app.nrbNr.Text = num2str(app.resBlk);

            end      
        end
        
        function setBandwidth(app)
            selected = app.rmc.NDLRB;
 
            switch selected
                case 6
                    app.BandwidthDropDown.Value = "1.4 MHz";
                case 15
                    app.BandwidthDropDown.Value = "3 MHz";
                case 25
                    app.BandwidthDropDown.Value = "5 MHz";
                case 50
                    app.BandwidthDropDown.Value = "10 MHz";
                case 100
                    app.BandwidthDropDown.Value = "20 MHz";
            end
        end
        
        function setCodeRate(app)
            rate = app.rmc.PDSCH.TargetCodeRate;
 
            switch rate
                case 1/2
                    app.CodingRateDropDown.Value = "1/2";
                case 1/3
                    app.CodingRateDropDown.Value = "1/3";
                case 3/4
                    app.CodingRateDropDown.Value = "3/4";
            end
        end
        
        function parseResults(app,result)
            if (result.sibCrc == 0)
                app.DecodedLamp.Color = "0.00,1.00,0.00";
            else
                app.DecodedLamp.Color = "1.00,0.00,0.00";
            end

            app.rmsEVM.Text = num2str(result.rmsEVM);
            app.pdschPeakEvm.Text = num2str(result.peakEVM);
            app.sibcrc.Text = num2str(result.sibCrc);
            app.rnti.Text = num2str(result.pdsch.RNTI);
            app.resModul.Text = result.pdsch.Modulation{1};
            app.ndlrb.Text = num2str(result.enb.NDLRB);
            app.resCellId.Text = num2str(result.enb.NCellID);
            app.resNg.Text = num2str(result.enb.Ng);
            app.resNumSubf.Text = num2str(result.enb.NSubframe);
            app.duplexResMode.Text = result.enb.DuplexMode;
            app.resCSI.Text = result.pdsch.CSI;
            app.resTxScheme.Text = result.pdsch.TxScheme;
            app.resNumOfLay.Text = num2str(result.pdsch.NLayers);
            
           
        end

        function parseResultsNR(app,result)
              disp(result);
              if (result.sib1CRC == 0)
                 app.DecodedLamp_2.Color = "0.00,1.00,0.00";
              else
                app.DecodedLamp_2.Color = "1.00,0.00,0.00";
              end

              app.pdschrmsevm.Text = num2str(result.pdschEVMrms);
              app.pdcchRmsEvm.Text = num2str(result.pdcchEVMrms);
              app.sibcrc_2.Text = num2str(result.sib1CRC);
              app.dcicrc.Text = num2str(result.sib1CRC);
              app.subCarrierSpace.Text = num2str(result.initialSystemInfo.SubcarrierSpacingCommon);
              app.resCellId_2.Text = num2str(result.c0Carrier.NCellID);
              app.ndlrb_2.Text = num2str(result.c0Carrier.NSizeGrid);
              app.resModul_2.Text = app.ModulationDropDown_2.Value;
              app.DMRSTypeAPosition.Text = num2str(result.initialSystemInfo.DMRSTypeAPosition);
              app.cyclePrefix.Text = result.c0Carrier.CyclicPrefix;
         
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('./LTE');
            addpath('./5G');
            addpath('./assets');
            app.lteSet = false;
            app.nrSet = false;
            app.rmc = lteRMCDL('R.3');
            initLte(app,true);
            app.lteSet = true;
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if ((selectedTab) == "LTE" && ~app.lteSet)
                app.rmc = lteRMCDL('R.3');
                initLte(app,true);
            elseif (~app.nrSet)
                initNR(app,true);
                app.nrSet = true;
            end

        end

        % Value changed function: LTERefModeDropDown
        function LTERefModeDropDownValueChanged(app, event)
            app.rmc = lteRMCDL(app.LTERefModeDropDown.Value);
            initLte(app,true);
        end

        % Button pushed function: RUNButton_2
        function RUNButton_2Pushed(app, event)
            try
                initLte(app,false);
                app.RUNButton_2.Enable = false;
                app.RESETButton.Enable = false;
                app.RunningLamp.Color = "0.00,1.00,0.00";
                app.DecodedLamp.Color = "1.00,0.00,0.00";
                
                app.fnRoot = sprintf("Results/LTE");
                
                app.result = lteCellSearchSync( ...
                    app.rmc, ...
                    app.ChannelModelButtonGroup.SelectedObject.Text, ...
                    [app.Delay1EditField.Value app.Delay2EditField.Value],...
                    [app.PathGain1EditField.Value app.PathGain2EditField.Value],...
                    app.SNREditField.Value,...
                    app.fnRoot,...
                    true,...
                    app.TransmittingLamp,...
                    app.ReceivingLamp,...
                    app.DecodingLamp);
                parseResults(app, app.result);
                app.RunningLamp.Color = "1.00,0.00,0.00";
                app.RUNButton_2.Enable = true;
                app.RESETButton.Enable = true;
                
                date = datestr(now,'mmmm-dd-yyyy-HH:MM');
                app.fname = sprintf("%s/%s-result.mat",app.fnRoot,date);
                app.resultDir.Text = app.fname;
            catch e
                errordlg(e.message, e.identifier);
            end
        end

        % Selection changed function: ChannelModelButtonGroup
        function ChannelModelButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChannelModelButtonGroup.SelectedObject;
            if(selectedButton.Text == "Rayleigh") 
                app.Delay1EditField.Enable = true;
                app.Delay1EditField.Editable = true;
                app.Delay1EditFieldLabel.Enable = true;
                app.Delay2EditField.Enable = true;
                app.Delay2EditField.Editable = true;
                app.Delay2EditFieldLabel.Enable = true;
                app.PathGain1EditField.Enable = true;
                app.PathGain1EditField.Editable = true;
                app.PathGain1EditFieldLabel.Enable = true;
                app.PathGain2EditField.Enable = true;
                app.PathGain2EditField.Editable = true;
                app.PathGain2EditFieldLabel.Enable = true;
            else
                app.Delay1EditField.Enable = false;
                app.Delay1EditField.Editable = false;
                app.Delay1EditFieldLabel.Enable = false;
                app.Delay2EditField.Enable = false;
                app.Delay2EditField.Editable = false;
                app.Delay2EditFieldLabel.Enable = false;
                app.PathGain1EditField.Enable = false;
                app.PathGain1EditField.Editable = false;
                app.PathGain1EditFieldLabel.Enable = false;
                app.PathGain2EditField.Enable = false;
                app.PathGain2EditField.Editable = false;
                app.PathGain2EditFieldLabel.Enable = false;
            end
    

        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.rmc = lteRMCDL('R.3');
            initLte(app);
            app.VRBSTARTSpinner.Value = 8;
            app.VRBLengthSpinner.Value = 8;
            app.GapSpinner.Value = 0;
            app.CELLIDEditField.Value = 17;
            app.RNTIEditField.Value = 61;
            app.SubframesEditField.Value = 41;

            app.DecodedLamp.Color = "1.00,0.00,0.00";
            app.rmsEVM.Text = "0";
            app.pdschPeakEvm.Text = "0";
            app.sibcrc.Text = "0";
            app.rnti.Text = "0";
            app.resModul.Text = "";
            app.ndlrb.Text = "0";
            app.resCellId.Text = "0";
            app.resNg.Text = "0";
            app.resNumSubf.Text = "0";
            app.duplexResMode.Text = "0";
            app.resCSI.Text = "0";
            app.resTxScheme.Text = "0";
            app.resNumOfLay.Text = "0";

        end

        % Value changed function: BandwidthDropDown
        function BandwidthDropDownValueChanged(app, event)
            value = app.BandwidthDropDown.Value;
            switch value
                case "1.4 MHz"
                    app.rmc.NDLRB = 6;
                case "3 MHz"
                    app.rmc.NDLRB = 15;
                case "5 MHz"
                    app.rmc.NDLRB = 25;
                case "10 MHz"
                    app.rmc.NDLRB = 50;
                case "20 MHz"
                    app.rmc.NDLRB = 100;
            end
        end

        % Value changed function: CodingRateDropDown
        function CodingRateDropDownValueChanged(app, event)
            value = app.CodingRateDropDown.Value;
            
            switch value
                case "1/2"
                    app.rmc.PDSCH.TargetCodeRate = 1/2;
                case "1/3"
                    app.rmc.PDSCH.TargetCodeRate = 1/3;
                case "3/4"
                    app.rmc.PDSCH.TargetCodeRate = 3/4;
            end
        end

        % Value changed function: NumofAntDropDown
        function NumofAntDropDownValueChanged(app, event)
            value = app.NumofAntDropDown.Value;

            switch value
                case "1"
                    app.rmc.PDSCH.NLayers = 1;
                case "2"
                    app.rmc.PDSCH.NLayers = 2;
                case "4"
                    app.rmc.PDSCH.NLayers = 4;
            end
        end

        % Button pushed function: RUNButton_3
        function RUNButton_3Pushed(app, event)
            try
                initNR(app,false);
    
                app.RUNButton_3.Enable = false;
                app.RESETButton_2.Enable = false;
                app.RunningLamp_2.Color = "0.00,1.00,0.00";
                app.DecodedLamp_2.Color = "1.00,0.00,0.00";
                
                app.fnRoot = sprintf("Results/NR");
                app.result = NRCellSearchSync( ...
                  app.wavegenConfig,...
                  app.nrConfig,...
                  app.ChannelModelButtonGroup.SelectedObject.Text, ...
                  [app.Delay1EditField_2.Value app.Delay2EditField_2.Value],...
                  [app.PathGain1EditField_2.Value app.PathGain2EditField_2.Value],...
                  app.IndexofSSBboostEditField.Value,...
                  app.SNRBoostBeamformingEditField.Value,...
                  app.nrbSSBEditField.Value,...
                  app.SNREditField_2.Value,...
                  app.fnRoot,... 
                  true,...
                  app.TransmittingLamp_2,...
                  app.ReceivingLamp_2,...
                  app.DecodingLamp_2);
                parseResultsNR(app, app.result);
                app.RunningLamp_2.Color = "1.00,0.00,0.00";
                app.RUNButton_3.Enable = true;
                app.RESETButton_2.Enable = true;
                
                date = datestr(now,'mmmm-dd-yyyy-HH:MM');
                app.fname = sprintf("%s/%s-result.mat",app.fnRoot,date);
                app.resultDir.Text = app.fname;
            catch e
                errordlg(e.message, e.identifier);
            end


        end

        % Callback function
        function MCSIndexEditFieldValueChanged(app, event)

        end

        % Selection changed function: ChannelModelButtonGroup_2
        function ChannelModelButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ChannelModelButtonGroup_2.SelectedObject;
                     
            if(selectedButton.Text == "Rayleigh") 
                app.Delay1EditField_2.Enable = true;
                app.Delay1EditField_2.Editable = true;
                app.Delay1EditFieldLabel.Enable = true;
                app.Delay2EditField_2.Enable = true;
                app.Delay2EditField_2.Editable = true;
                app.Delay2EditFieldLabel.Enable = true;
                app.PathGain1EditField_2.Enable = true;
                app.PathGain1EditField_2.Editable = true;
                app.PathGain1EditFieldLabel.Enable = true;
                app.PathGain2EditField_2.Enable = true;
                app.PathGain2EditField_2.Editable = true;
                app.PathGain2EditFieldLabel.Enable = true;
            else
                app.Delay1EditField_2.Enable = false;
                app.Delay1EditField_2.Editable = false;
                app.Delay1EditFieldLabel.Enable = false;
                app.Delay2EditField_2.Enable = false;
                app.Delay2EditField_2.Editable = false;
                app.Delay2EditFieldLabel.Enable = false;
                app.PathGain1EditField_2.Enable = false;
                app.PathGain1EditField_2.Editable = false;
                app.PathGain1EditFieldLabel.Enable = false;
                app.PathGain2EditField_2.Enable = false;
                app.PathGain2EditField_2.Editable = false;
                app.PathGain2EditFieldLabel.Enable = false;
            end
        end

        % Value changed function: BandwidthDropDown_2
        function BandwidthDropDown_2ValueChanged(app, event)
            bandw = extract(app.BandwidthDropDown_2.Value,digitsPattern);
            app.bw = str2num(bandw{1});

            ssbSpace = hSSBurstSubcarrierSpacing(app.nrConfig.BlockPattern);

            % Set the minimum channel bandwidth for the NR band required to
            % configure CORESET0 in FR1 (See TS 38.101-1 Table 5.3.5-1)
            switch ssbSpace
                case 15
                    app.nrConfig.Bandwidth = 50;
                    app.resBlk = ceilDiv(app.bw, 0.36);
                    app.nrConfig.NSizeGrid = app.resBlk;
                case 30
                    app.nrConfig.Bandwidth = 100;
                    app.resBlk = ceilDiv(app.bw, 0.36);
                    app.nrConfig.NSizeGrid = app.resBlk; 
                case 60
                    app.nrConfig.Bandwidth = 200;
                    app.resBlk = ceilDiv(app.bw, 0.72);
                    app.nrConfig.NSizeGrid = app.resBlk; 
                case 120
                    app.nrConfig.Bandwidth = 400;
                    app.resBlk = ceilDiv(app.bw, 1.44);
                    app.nrConfig.NSizeGrid = app.resBlk; 
                case 240
                    app.nrConfig.Bandwidth = 400;
                    app.resBlk = ceilDiv(app.bw, 2.88);
                    app.nrConfig.NSizeGrid = app.resBlk; 
            end
            app.nrbNr.Text = num2str(app.resBlk);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ECE575ProjectUIFigure = uifigure('Visible', 'off');
            app.ECE575ProjectUIFigure.Name = 'ECE 575 Project';
            app.GridLayout = uigridlayout(app.ECE575ProjectUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.LTETab = uitab(app.TabGroup);
            app.LTETab.Title = 'LTE';
            app.GridLayout3 = uigridlayout(app.LTETab);
            app.ChannelModelButtonGroup = uibuttongroup(app.GridLayout3);
            app.ChannelModelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChannelModelButtonGroupSelectionChanged, true);
            app.ChannelModelButtonGroup.Title = 'Channel Model';
            app.AWGNButton_2 = uiradiobutton(app.ChannelModelButtonGroup);
            app.AWGNButton_2.Text = 'AWGN';
            app.AWGNButton_2.Value = true;
            app.RayleighButton = uiradiobutton(app.ChannelModelButtonGroup);
            app.RayleighButton.Text = 'Rayleigh';
            app.LTERefModeDropDownLabel = uilabel(app.GridLayout3);
            app.LTERefModeDropDownLabel.Text = {'LTE '; 'Ref. Mode'};
            app.LTERefModeDropDown = uidropdown(app.GridLayout3);
            app.LTERefModeDropDown.Items = {'R.0', 'R.1', 'R.2', 'R.3', 'R.4', 'R.5', 'R.6', 'R.7', 'R.8', 'R.9', 'R.10', 'R.11', 'R.12', 'R.13', 'R.14', 'R.25', 'R.26', 'R.27', 'R.28', 'R.45'};
            app.LTERefModeDropDown.ValueChangedFcn = createCallbackFcn(app, @LTERefModeDropDownValueChanged, true);
            app.LTERefModeDropDown.Value = 'R.3';
            app.CELLIDEditFieldLabel = uilabel(app.GridLayout3);
            app.CELLIDEditFieldLabel.Text = 'CELL ID';
            app.CELLIDEditField = uieditfield(app.GridLayout3, 'numeric');
            app.CELLIDEditField.Value = 17;
            app.RNTIEditFieldLabel = uilabel(app.GridLayout3);
            app.RNTIEditFieldLabel.Text = 'RNTI';
            app.RNTIEditField = uieditfield(app.GridLayout3, 'numeric');
            app.RNTIEditField.Value = 61;
            app.SIBEnableCheckBox = uicheckbox(app.GridLayout3);
            app.SIBEnableCheckBox.Text = 'SIB Enable';
            app.SIBEnableCheckBox.Value = true;
            app.SIBLabel = uilabel(app.GridLayout3);
            app.SIBLabel.Text = 'SIB';
            app.SubframesEditFieldLabel = uilabel(app.GridLayout3);
            app.SubframesEditFieldLabel.Text = 'Subframes';
            app.SubframesEditField = uieditfield(app.GridLayout3, 'numeric');
            app.SubframesEditField.Value = 41;
            app.CycPrefixLabel = uilabel(app.GridLayout3);
            app.CycPrefixLabel.Text = {'Cyc Prefix'; ''};
            app.CycPrefixDropDown = uidropdown(app.GridLayout3);
            app.CycPrefixDropDown.Items = {'Normal', 'Extended'};
            app.CycPrefixDropDown.Value = 'Normal';
            app.ParametersLabel = uilabel(app.GridLayout3);
            app.ParametersLabel.Text = 'Parameters';
            app.PHICHDurationLabel = uilabel(app.GridLayout3);
            app.PHICHDurationLabel.Text = 'PHICH Duration';
            app.phichDuration = uilabel(app.GridLayout3);
            app.phichDuration.Text = 'Normal';
            app.RESETButton = uibutton(app.GridLayout3, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.RUNButton_2 = uibutton(app.GridLayout3, 'push');
            app.RUNButton_2.ButtonPushedFcn = createCallbackFcn(app, @RUNButton_2Pushed, true);
            app.RUNButton_2.Text = 'RUN';
            app.CFILabel = uilabel(app.GridLayout3);
            app.CFILabel.Text = 'CFI';
            app.cfi = uilabel(app.GridLayout3);
            app.cfi.Text = '1';
            app.TotalSubframesLabel = uilabel(app.GridLayout3);
            app.TotalSubframesLabel.Text = 'Total Subframes';
            app.totSubframes = uilabel(app.GridLayout3);
            app.totSubframes.Text = '0';
            app.NumberofFrameLabel = uilabel(app.GridLayout3);
            app.NumberofFrameLabel.Text = 'Number of Frame';
            app.numOfFrame = uilabel(app.GridLayout3);
            app.numOfFrame.Text = '0';
            app.CellRefPortsLabel = uilabel(app.GridLayout3);
            app.CellRefPortsLabel.Text = 'Cell Ref Ports';
            app.cellRefPorts = uilabel(app.GridLayout3);
            app.cellRefPorts.Text = '1';
            app.SNREditFieldLabel = uilabel(app.GridLayout3);
            app.SNREditFieldLabel.Text = 'SNR';
            app.SNREditField = uieditfield(app.GridLayout3, 'numeric');
            app.SNREditField.Value = 30;
            app.DCIFormatDropDownLabel = uilabel(app.GridLayout3);
            app.DCIFormatDropDownLabel.Text = 'DCI Format';
            app.DCIFormatDropDown = uidropdown(app.GridLayout3);
            app.DCIFormatDropDown.Items = {'Format1A', 'Extended'};
            app.DCIFormatDropDown.Value = 'Format1A';
            app.VRBSTARTSpinnerLabel = uilabel(app.GridLayout3);
            app.VRBSTARTSpinnerLabel.Text = 'VRBSTART';
            app.VRBSTARTSpinner = uispinner(app.GridLayout3);
            app.VRBSTARTSpinner.Value = 8;
            app.VRBLengthSpinnerLabel = uilabel(app.GridLayout3);
            app.VRBLengthSpinnerLabel.Text = 'VRBLength';
            app.VRBLengthSpinner = uispinner(app.GridLayout3);
            app.VRBLengthSpinner.Value = 8;
            app.GapSpinnerLabel = uilabel(app.GridLayout3);
            app.GapSpinnerLabel.Text = 'Gap';
            app.GapSpinner = uispinner(app.GridLayout3);
            app.RunningLampLabel = uilabel(app.GridLayout3);
            app.RunningLampLabel.Text = 'Running';
            app.RunningLamp = uilamp(app.GridLayout3);
            app.SimulationLabel = uilabel(app.GridLayout3);
            app.SimulationLabel.Text = 'Simulation';
            app.Delay1EditFieldLabel = uilabel(app.GridLayout3);
            app.Delay1EditFieldLabel.Text = 'Delay 1';
            app.Delay1EditField = uieditfield(app.GridLayout3, 'numeric');
            app.Delay1EditField.Editable = 'off';
            app.Delay1EditField.Enable = 'off';
            app.ResultsLabel = uilabel(app.GridLayout3);
            app.ResultsLabel.Text = 'Results';
            app.TransmittingLampLabel = uilabel(app.GridLayout3);
            app.TransmittingLampLabel.Text = 'Transmitting';
            app.TransmittingLamp = uilamp(app.GridLayout3);
            app.ReceivingLampLabel = uilabel(app.GridLayout3);
            app.ReceivingLampLabel.Text = 'Receiving';
            app.ReceivingLamp = uilamp(app.GridLayout3);
            app.DecodingLampLabel = uilabel(app.GridLayout3);
            app.DecodingLampLabel.Text = 'Decoding';
            app.DecodingLamp = uilamp(app.GridLayout3);
            app.DecodedLampLabel = uilabel(app.GridLayout3);
            app.DecodedLampLabel.Text = 'Decoded';
            app.DecodedLamp = uilamp(app.GridLayout3);
            app.PDSCHRMSEVMLabel = uilabel(app.GridLayout3);
            app.PDSCHRMSEVMLabel.Text = 'PDSCH RMS EVM';
            app.rmsEVM = uilabel(app.GridLayout3);
            app.rmsEVM.Text = '0';
            app.PDSCHPeakEVMLabel = uilabel(app.GridLayout3);
            app.PDSCHPeakEVMLabel.Text = 'PDSCH Peak EVM';
            app.pdschPeakEvm = uilabel(app.GridLayout3);
            app.pdschPeakEvm.Text = '0';
            app.SIB1CRCLabel = uilabel(app.GridLayout3);
            app.SIB1CRCLabel.Text = 'SIB1 CRC';
            app.sibcrc = uilabel(app.GridLayout3);
            app.sibcrc.Text = '0';
            app.RNTILabel = uilabel(app.GridLayout3);
            app.RNTILabel.Text = 'RNTI';
            app.rnti = uilabel(app.GridLayout3);
            app.rnti.Text = '0';
            app.Delay2EditFieldLabel = uilabel(app.GridLayout3);
            app.Delay2EditFieldLabel.Text = 'Delay 2';
            app.Delay2EditField = uieditfield(app.GridLayout3, 'numeric');
            app.Delay2EditField.Editable = 'off';
            app.Delay2EditField.Enable = 'off';
            app.Delay2EditField.Value = 0.00015;
            app.NDLRBLabel = uilabel(app.GridLayout3);
            app.NDLRBLabel.Text = 'NDLRB';
            app.ndlrb = uilabel(app.GridLayout3);
            app.ndlrb.Text = '0';
            app.CellIDLabel_5 = uilabel(app.GridLayout3);
            app.CellIDLabel_5.Text = 'Cell ID';
            app.DuplexModeLabel_2 = uilabel(app.GridLayout3);
            app.DuplexModeLabel_2.Text = 'Duplex Mode';
            app.resCellId = uilabel(app.GridLayout3);
            app.resCellId.Text = '0';
            app.NumSubfrLabel = uilabel(app.GridLayout3);
            app.NumSubfrLabel.Text = 'Num. Subfr.';
            app.NgLabel = uilabel(app.GridLayout3);
            app.NgLabel.Text = 'Ng';
            app.duplexResMode = uilabel(app.GridLayout3);
            app.duplexResMode.Text = 'TDD';
            app.resNumSubf = uilabel(app.GridLayout3);
            app.resNumSubf.Text = '0';
            app.resNg = uilabel(app.GridLayout3);
            app.resNg.Text = '0';
            app.ModulationLabel_2 = uilabel(app.GridLayout3);
            app.ModulationLabel_2.Text = 'Modulation';
            app.resModul = uilabel(app.GridLayout3);
            app.resModul.Text = 'QPSK';
            app.CSILabel = uilabel(app.GridLayout3);
            app.CSILabel.Text = 'CSI';
            app.resCSI = uilabel(app.GridLayout3);
            app.resCSI.Text = 'On';
            app.TxSchemeLabel = uilabel(app.GridLayout3);
            app.TxSchemeLabel.Text = 'Tx Scheme';
            app.resTxScheme = uilabel(app.GridLayout3);
            app.resTxScheme.Text = 'Port0';
            app.NumofLayersLabel = uilabel(app.GridLayout3);
            app.NumofLayersLabel.Text = 'Num of Layers';
            app.resNumOfLay = uilabel(app.GridLayout3);
            app.resNumOfLay.Text = '1';
            app.resultDir = uilabel(app.GridLayout3);
            app.resultDir.Text = '';
            app.ResultsDirectoryLabel = uilabel(app.GridLayout3);
            app.ResultsDirectoryLabel.Text = 'Results Directory';
            app.BandwidthDropDownLabel = uilabel(app.GridLayout3);
            app.BandwidthDropDownLabel.Text = 'Bandwidth';
            app.BandwidthDropDown = uidropdown(app.GridLayout3);
            app.BandwidthDropDown.Items = {'1.4 MHz', '3 MHz', '5 MHz', '10 MHz', '15 MHz', '20 MHz'};
            app.BandwidthDropDown.ValueChangedFcn = createCallbackFcn(app, @BandwidthDropDownValueChanged, true);
            app.BandwidthDropDown.Value = '1.4 MHz';
            app.ModulationDropDownLabel = uilabel(app.GridLayout3);
            app.ModulationDropDownLabel.Text = 'Modulation';
            app.ModulationDropDown = uidropdown(app.GridLayout3);
            app.ModulationDropDown.Items = {'QPSK', '16QAM', '64QAM', '256QAM', '1024QAM'};
            app.ModulationDropDown.Value = 'QPSK';
            app.CodingRateDropDownLabel = uilabel(app.GridLayout3);
            app.CodingRateDropDownLabel.Text = 'Coding Rate';
            app.CodingRateDropDown = uidropdown(app.GridLayout3);
            app.CodingRateDropDown.Items = {'1/2', '1/3', '3/4'};
            app.CodingRateDropDown.ValueChangedFcn = createCallbackFcn(app, @CodingRateDropDownValueChanged, true);
            app.CodingRateDropDown.Value = '1/2';
            app.DuplexModeDropDownLabel = uilabel(app.GridLayout3);
            app.DuplexModeDropDownLabel.Text = 'Duplex Mode';
            app.DuplexModeDropDown = uidropdown(app.GridLayout3);
            app.DuplexModeDropDown.Items = {'FDD', 'TDD'};
            app.DuplexModeDropDown.Value = 'FDD';
            app.NumofAntDropDownLabel = uilabel(app.GridLayout3);
            app.NumofAntDropDownLabel.Text = 'Num of Ant.';
            app.NumofAntDropDown = uidropdown(app.GridLayout3);
            app.NumofAntDropDown.Items = {'1', '2', '4'};
            app.NumofAntDropDown.ValueChangedFcn = createCallbackFcn(app, @NumofAntDropDownValueChanged, true);
            app.NumofAntDropDown.Value = '1';
            app.PathGain1EditFieldLabel = uilabel(app.GridLayout3);
            app.PathGain1EditFieldLabel.Text = 'Path Gain 1';
            app.PathGain1EditField = uieditfield(app.GridLayout3, 'numeric');
            app.PathGain1EditField.Editable = 'off';
            app.PathGain1EditField.Enable = 'off';
            app.PathGain1EditField.Value = 2;
            app.PathGain2EditFieldLabel = uilabel(app.GridLayout3);
            app.PathGain2EditFieldLabel.Text = 'Path Gain 2';
            app.PathGain2EditField = uieditfield(app.GridLayout3, 'numeric');
            app.PathGain2EditField.Editable = 'off';
            app.PathGain2EditField.Enable = 'off';
            app.PathGain2EditField.Value = 3;
            app.GTab = uitab(app.TabGroup);
            app.GTab.Title = '5G';
            app.GridLayout3_2 = uigridlayout(app.GTab);
            app.ChannelModelButtonGroup_2 = uibuttongroup(app.GridLayout3_2);
            app.ChannelModelButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ChannelModelButtonGroup_2SelectionChanged, true);
            app.ChannelModelButtonGroup_2.Title = 'Channel Model';
            app.AWGNButton_3 = uiradiobutton(app.ChannelModelButtonGroup_2);
            app.AWGNButton_3.Text = 'AWGN';
            app.AWGNButton_3.Value = true;
            app.RayleighButton_2 = uiradiobutton(app.ChannelModelButtonGroup_2);
            app.RayleighButton_2.Text = 'Rayleigh';
            app.BlockPatternDropDownLabel = uilabel(app.GridLayout3_2);
            app.BlockPatternDropDownLabel.Text = 'Block Pattern';
            app.BlockPatternDropDown = uidropdown(app.GridLayout3_2);
            app.BlockPatternDropDown.Items = {'Case A', 'Case B', 'Case C', 'Case D', 'Case E'};
            app.BlockPatternDropDown.Value = 'Case A';
            app.CELLIDEditField_2Label = uilabel(app.GridLayout3_2);
            app.CELLIDEditField_2Label.Text = 'CELL ID';
            app.CELLIDEditField_2 = uieditfield(app.GridLayout3_2, 'numeric');
            app.CELLIDEditField_2.Value = 17;
            app.SubcarrierSpacingCommonEditFieldLabel = uilabel(app.GridLayout3_2);
            app.SubcarrierSpacingCommonEditFieldLabel.Text = 'SubcarrierSpacingCommon';
            app.SubcarrierSpacingCommonEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.SubcarrierSpacingCommonEditField.Value = 15;
            app.SIBEnableCheckBox_2 = uicheckbox(app.GridLayout3_2);
            app.SIBEnableCheckBox_2.Text = 'SIB Enable';
            app.SIBEnableCheckBox_2.Value = true;
            app.SIBLabel_2 = uilabel(app.GridLayout3_2);
            app.SIBLabel_2.Text = 'SIB';
            app.nrbSSBEditFieldLabel = uilabel(app.GridLayout3_2);
            app.nrbSSBEditFieldLabel.Text = 'nrbSSB';
            app.nrbSSBEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.nrbSSBEditField.Value = 20;
            app.MinChannelBWMHzDropDownLabel = uilabel(app.GridLayout3_2);
            app.MinChannelBWMHzDropDownLabel.Text = 'Min Channel BW (MHz)';
            app.MinChannelBWMHzDropDown = uidropdown(app.GridLayout3_2);
            app.MinChannelBWMHzDropDown.Items = {'5', '10', '40', '70'};
            app.MinChannelBWMHzDropDown.Value = '5';
            app.ParametersLabel_2 = uilabel(app.GridLayout3_2);
            app.ParametersLabel_2.Text = 'Parameters';
            app.RESETButton_2 = uibutton(app.GridLayout3_2, 'push');
            app.RESETButton_2.Text = 'RESET';
            app.RUNButton_3 = uibutton(app.GridLayout3_2, 'push');
            app.RUNButton_3.ButtonPushedFcn = createCallbackFcn(app, @RUNButton_3Pushed, true);
            app.RUNButton_3.Text = 'RUN';
            app.SNREditField_2Label = uilabel(app.GridLayout3_2);
            app.SNREditField_2Label.Text = 'SNR';
            app.SNREditField_2 = uieditfield(app.GridLayout3_2, 'numeric');
            app.SNREditField_2.Value = 30;
            app.RunningLamp_2Label = uilabel(app.GridLayout3_2);
            app.RunningLamp_2Label.Text = 'Running';
            app.RunningLamp_2 = uilamp(app.GridLayout3_2);
            app.SimulationLabel_2 = uilabel(app.GridLayout3_2);
            app.SimulationLabel_2.Text = 'Simulation';
            app.Delay1EditField_2Label = uilabel(app.GridLayout3_2);
            app.Delay1EditField_2Label.Text = 'Delay 1';
            app.Delay1EditField_2 = uieditfield(app.GridLayout3_2, 'numeric');
            app.Delay1EditField_2.Editable = 'off';
            app.Delay1EditField_2.Enable = 'off';
            app.ResultsLabel_2 = uilabel(app.GridLayout3_2);
            app.ResultsLabel_2.Text = 'Results';
            app.TransmittingLamp_2Label = uilabel(app.GridLayout3_2);
            app.TransmittingLamp_2Label.Text = 'Transmitting';
            app.TransmittingLamp_2 = uilamp(app.GridLayout3_2);
            app.ReceivingLamp_2Label = uilabel(app.GridLayout3_2);
            app.ReceivingLamp_2Label.Text = 'Receiving';
            app.ReceivingLamp_2 = uilamp(app.GridLayout3_2);
            app.DecodingLamp_2Label = uilabel(app.GridLayout3_2);
            app.DecodingLamp_2Label.Text = 'Decoding';
            app.DecodingLamp_2 = uilamp(app.GridLayout3_2);
            app.DecodedLamp_2Label = uilabel(app.GridLayout3_2);
            app.DecodedLamp_2Label.Text = 'Decoded';
            app.DecodedLamp_2 = uilamp(app.GridLayout3_2);
            app.PDSCHRMSEVMLabel_2 = uilabel(app.GridLayout3_2);
            app.PDSCHRMSEVMLabel_2.Text = 'PDSCH RMS EVM';
            app.pdschrmsevm = uilabel(app.GridLayout3_2);
            app.pdschrmsevm.Text = '0';
            app.PDCCHRMSEVMLabel = uilabel(app.GridLayout3_2);
            app.PDCCHRMSEVMLabel.Text = 'PDCCH RMS EVM';
            app.pdcchRmsEvm = uilabel(app.GridLayout3_2);
            app.pdcchRmsEvm.Text = '0';
            app.SIB1CRCLabel_2 = uilabel(app.GridLayout3_2);
            app.SIB1CRCLabel_2.Text = 'SIB1 CRC';
            app.sibcrc_2 = uilabel(app.GridLayout3_2);
            app.sibcrc_2.Text = '0';
            app.Delay2EditField_2Label = uilabel(app.GridLayout3_2);
            app.Delay2EditField_2Label.Text = 'Delay 2';
            app.Delay2EditField_2 = uieditfield(app.GridLayout3_2, 'numeric');
            app.Delay2EditField_2.Editable = 'off';
            app.Delay2EditField_2.Enable = 'off';
            app.Delay2EditField_2.Value = 0.00015;
            app.NDLRBLabel_2 = uilabel(app.GridLayout3_2);
            app.NDLRBLabel_2.Text = 'NDLRB';
            app.ndlrb_2 = uilabel(app.GridLayout3_2);
            app.ndlrb_2.Text = '0';
            app.CellIDLabel_6 = uilabel(app.GridLayout3_2);
            app.CellIDLabel_6.Text = 'Cell ID';
            app.resCellId_2 = uilabel(app.GridLayout3_2);
            app.resCellId_2.Text = '0';
            app.NumSubfrLabel_2 = uilabel(app.GridLayout3_2);
            app.NumSubfrLabel_2.Text = 'Num. Subfr.';
            app.DMRSTypeAPositionLabel = uilabel(app.GridLayout3_2);
            app.DMRSTypeAPositionLabel.Text = 'DMRSTypeAPosition';
            app.resNumSubf_2 = uilabel(app.GridLayout3_2);
            app.resNumSubf_2.Text = '0';
            app.DMRSTypeAPosition = uilabel(app.GridLayout3_2);
            app.DMRSTypeAPosition.Text = '0';
            app.ModulationLabel_3 = uilabel(app.GridLayout3_2);
            app.ModulationLabel_3.Text = 'Modulation';
            app.resModul_2 = uilabel(app.GridLayout3_2);
            app.resModul_2.Text = 'QPSK';
            app.CSILabel_2 = uilabel(app.GridLayout3_2);
            app.CSILabel_2.Text = 'CSI';
            app.resCSI_2 = uilabel(app.GridLayout3_2);
            app.resCSI_2.Text = 'On';
            app.CyclePrefixLabel = uilabel(app.GridLayout3_2);
            app.CyclePrefixLabel.Text = 'Cycle Prefix';
            app.cyclePrefix = uilabel(app.GridLayout3_2);
            app.cyclePrefix.Text = 'Normal';
            app.SubcarrierSpLabel = uilabel(app.GridLayout3_2);
            app.SubcarrierSpLabel.Text = 'Subcarrier Sp.';
            app.subCarrierSpace = uilabel(app.GridLayout3_2);
            app.subCarrierSpace.Text = '15';
            app.resultDir_2 = uilabel(app.GridLayout3_2);
            app.resultDir_2.Text = '';
            app.ResultsDirectoryLabel_2 = uilabel(app.GridLayout3_2);
            app.ResultsDirectoryLabel_2.Text = 'Results Directory';
            app.BandwidthDropDown_2Label = uilabel(app.GridLayout3_2);
            app.BandwidthDropDown_2Label.Text = 'Bandwidth';
            app.BandwidthDropDown_2 = uidropdown(app.GridLayout3_2);
            app.BandwidthDropDown_2.Items = {'25 MHz', '50 MHz', '100 MHz', '150 MHz', '200 MHz', '250 MHz', '300 MHz', '400 MHz'};
            app.BandwidthDropDown_2.ValueChangedFcn = createCallbackFcn(app, @BandwidthDropDown_2ValueChanged, true);
            app.BandwidthDropDown_2.Value = '25 MHz';
            app.ModulationDropDown_2Label = uilabel(app.GridLayout3_2);
            app.ModulationDropDown_2Label.Text = 'Modulation';
            app.ModulationDropDown_2 = uidropdown(app.GridLayout3_2);
            app.ModulationDropDown_2.Items = {'QPSK', '16QAM', '64QAM', '256QAM', '1024QAM'};
            app.ModulationDropDown_2.Value = 'QPSK';
            app.NumofAntDropDown_2Label = uilabel(app.GridLayout3_2);
            app.NumofAntDropDown_2Label.Text = 'Num of Ant.';
            app.NumofAntDropDown_2 = uidropdown(app.GridLayout3_2);
            app.NumofAntDropDown_2.Items = {'1', '2', '4'};
            app.NumofAntDropDown_2.Value = '1';
            app.PathGain1EditField_2Label = uilabel(app.GridLayout3_2);
            app.PathGain1EditField_2Label.Text = 'Path Gain 1';
            app.PathGain1EditField_2 = uieditfield(app.GridLayout3_2, 'numeric');
            app.PathGain1EditField_2.Editable = 'off';
            app.PathGain1EditField_2.Enable = 'off';
            app.PathGain1EditField_2.Value = 2;
            app.PathGain2EditField_2Label = uilabel(app.GridLayout3_2);
            app.PathGain2EditField_2Label.Text = 'Path Gain 2';
            app.PathGain2EditField_2 = uieditfield(app.GridLayout3_2, 'numeric');
            app.PathGain2EditField_2.Editable = 'off';
            app.PathGain2EditField_2.Enable = 'off';
            app.PathGain2EditField_2.Value = 3;
            app.SNRBoostBeamformingEditFieldLabel = uilabel(app.GridLayout3_2);
            app.SNRBoostBeamformingEditFieldLabel.Text = 'SNR Boost(Beamforming)';
            app.SNRBoostBeamformingEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.SNRBoostBeamformingEditField.Value = 20;
            app.IndexofSSBboostEditFieldLabel = uilabel(app.GridLayout3_2);
            app.IndexofSSBboostEditFieldLabel.Text = 'Index of SSB boost';
            app.IndexofSSBboostEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.CodingRateLabel = uilabel(app.GridLayout3_2);
            app.CodingRateLabel.Text = 'Coding Rate';
            app.codingRate = uilabel(app.GridLayout3_2);
            app.codingRate.Text = '1/2';
            app.NumberofresourceblocksLabel = uilabel(app.GridLayout3_2);
            app.NumberofresourceblocksLabel.Text = 'Number of resource blocks';
            app.nrbNr = uilabel(app.GridLayout3_2);
            app.nrbNr.Text = '6';
            app.DCICRCLabel = uilabel(app.GridLayout3_2);
            app.DCICRCLabel.Text = 'DCI CRC';
            app.dcicrc = uilabel(app.GridLayout3_2);
            app.dcicrc.Text = '0';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Image = uiimage(app.GridLayout2);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'assets', 'ncstate-type-4x1-red.png');
            app.ECE575CellSynchronizationProjectLabel = uilabel(app.GridLayout2);
            app.ECE575CellSynchronizationProjectLabel.Text = 'ECE 575 - Cell Synchronization Project';
            % Show the figure after all components are created
            app.ECE575ProjectUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ECE575ProjectUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ECE575ProjectUIFigure)
        end
    end
end